classdef ForwardQSM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForwardQSMSimulatorUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        StatusLabel                    matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        MRISimulationPanel             matlab.ui.container.Panel
        GridLayout2_3                  matlab.ui.container.GridLayout
        B0TEditField                   matlab.ui.control.NumericEditField
        B0TEditFieldLabel              matlab.ui.control.Label
        SNRdBEditField                 matlab.ui.control.NumericEditField
        SNRdBLabel                     matlab.ui.control.Label
        NoiseCheckBox                  matlab.ui.control.CheckBox
        FlipangledegEditFieldLabel     matlab.ui.control.Label
        FlipangledegEditField          matlab.ui.control.NumericEditField
        NoechoesEditFieldLabel         matlab.ui.control.Label
        NoechoesEditField              matlab.ui.control.NumericEditField
        DeltaTEsEditFieldLabel         matlab.ui.control.Label
        DeltaTEsEditField              matlab.ui.control.NumericEditField
        FirstTEsEditFieldLabel         matlab.ui.control.Label
        FirstTEsEditField              matlab.ui.control.NumericEditField
        MRISimulationButton            matlab.ui.control.Button
        TRsEditFieldLabel              matlab.ui.control.Label
        TRsEditField                   matlab.ui.control.NumericEditField
        FieldSimulationPanel           matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        BackgroundFieldDropDown        matlab.ui.control.DropDown
        BackgroundFieldDropDownLabel   matlab.ui.control.Label
        B0directionLabel               matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        SaveFieldButton                matlab.ui.control.Button
        LoadFieldButton                matlab.ui.control.Button
        ViewFieldButton                matlab.ui.control.Button
        SimulateFieldButton            matlab.ui.control.Button
        PhantomCreationPanel           matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        zEditField_3                   matlab.ui.control.NumericEditField
        zEditField_3Label              matlab.ui.control.Label
        yEditField_3                   matlab.ui.control.NumericEditField
        yEditField_3Label              matlab.ui.control.Label
        xEditField_3                   matlab.ui.control.NumericEditField
        xEditField_3Label              matlab.ui.control.Label
        MatrixsizeLabel                matlab.ui.control.Label
        zEditField_2                   matlab.ui.control.NumericEditField
        zEditField_2Label_2            matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label_2            matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        xEditField_2Label_2            matlab.ui.control.Label
        VoxelsizemmLabel               matlab.ui.control.Label
        SavePhantomButton              matlab.ui.control.Button
        LoadPhantomButton              matlab.ui.control.Button
        ViewPhantomButton              matlab.ui.control.Button
        CreatePhantomButton            matlab.ui.control.Button
        BackgroundtypeLabel            matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        CleartableButton               matlab.ui.control.Button
        RemoverowButton                matlab.ui.control.Button
        InsertrowButton                matlab.ui.control.Button
        AddrowButton                   matlab.ui.control.Button
        nSpinner                       matlab.ui.control.Spinner
        nSpinnerLabel                  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        SpecifyphantomparametersLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [STR, ~, ~] = fileparts(mfilename('fullpath'));
            run(fullfile(STR,'QSM_set_path.m'));
            
            app.UITable.Data = {'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0;...
                'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0;...
                'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0};
            app.UITable.ColumnFormat = {{'Custom','Caudate','Globus Pallidus','Putamen','Red Nucleus','Dentate Nucleus','Substantia Nigra & StN','Thalamus','White Matter','Gray Matter','CSF','Blood','Fat','Bone','Air','Muscle','Calcification'}};
            app.nSpinner.Limits = [1 size(app.UITable.Data,1)];
        end

        % Button pushed function: CreatePhantomButton
        function CreatePhantomButtonPushed(app, event)
            app.EditField.Value = 'Creating phantom...';
            drawnow();
            vox_size = [app.xEditField_2.Value, app.yEditField_2.Value, app.zEditField_2.Value];
            matrix_size = [app.xEditField_3.Value, app.yEditField_3.Value, app.zEditField_3.Value];
            create_susceptibility_map(app.UITable.Data,matrix_size, app.BackgroundDropDown.Value, vox_size);
            app.EditField.Value = 'Phantom created successfully';
            drawnow();
        end

        % Button pushed function: RemoverowButton
        function RemoverowButtonPushed(app, event)
            app.UITable.Data(app.nSpinner.Value,:) = [];
            app.nSpinner.Limits = [1 size(app.UITable.Data,1)];
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.UITable.Data(end+1,:) ={'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0};
            app.nSpinner.Limits = [1 size(app.UITable.Data,1)];
        end

        % Button pushed function: CleartableButton
        function CleartableButtonPushed(app, event)
            app.UITable.Data = {'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0;...
                'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0;...
                'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0};
            app.nSpinner.Limits = [1 size(app.UITable.Data,1)];
            app.EditField.Value = 'Table cleared';
            drawnow();
        end

        % Button pushed function: InsertrowButton
        function InsertrowButtonPushed(app, event)
            d = get(app.UITable,'data');
            if app.nSpinner.Value == 1
                before = {'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0};
            else
                before = d(1:app.nSpinner.Value-1,:);
                before(app.nSpinner.Value,:) = {'Custom' 0 0 0 0 0 0 0 0 0 0 0 0 0};
            end
            after = d(app.nSpinner.Value:end,:);
            total = [before;after];
            set(app.UITable,'data',total);
            app.nSpinner.Limits = [1 size(app.UITable.Data,1)];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 1 && ~strcmp(newData,'Custom')
                app.UITable.Data(indices(1),2:5) = check_tissue(newData);
            elseif indices(2) > 1 && indices(2) < 6
                app.UITable.Data(indices(1),1) = {'Custom'};
            end 
        end

        % Button pushed function: SavePhantomButton
        function SavePhantomButtonPushed(app, event)
            app.EditField.Value = 'Saving phantom...';
            drawnow();
            msg = save_phantom();
            app.EditField.Value = msg;
            drawnow();
            figure(app.ForwardQSMSimulatorUIFigure)
        end

        % Button pushed function: ViewPhantomButton
        function ViewPhantomButtonPushed(app, event)
            if evalin('base', 'exist("Phantom","var")==1')
                Phantom = evalin('base','Phantom');
                imshow3Dfull(Phantom.Chi);
            else
                app.EditField.Value = 'Phantom struct not found';
                drawnow();
            end
        end

        % Button pushed function: LoadPhantomButton
        function LoadPhantomButtonPushed(app, event)
            [file, path] = uigetfile;
            drawnow();
            figure(app.ForwardQSMSimulatorUIFigure)
            if ~isequal(file,0)
                app.EditField.Value = 'Loading phantom...';
                drawnow();
                evalin('base','clear Phantom')
                load(fullfile(path,file),'Phantom');
                assignin('base', 'Phantom', Phantom);
                app.UITable.Data = Phantom.DesignTable;
                app.MatrixsizeEditField.Value = Phantom.Params{1};
                app.BackgroundDropDown.Value = Phantom.Params{2};
                app.EditField.Value = 'Phantom loaded successfully';
                drawnow();
            end
        end

        % Button pushed function: SaveFieldButton
        function SaveFieldButtonPushed(app, event)
            app.EditField.Value = 'Saving demagnetizing field...';
            drawnow();
            msg = save_field();
            app.EditField.Value = msg;
            drawnow();
            figure(app.ForwardQSMSimulatorUIFigure)
        end

        % Button pushed function: LoadFieldButton
        function LoadFieldButtonPushed(app, event)
            [file, path] = uigetfile;
            drawnow();
            figure(app.ForwardQSMSimulatorUIFigure)
            if ~isequal(file,0)
                app.EditField.Value = 'Loading field...';
                drawnow();
                evalin('base','clear Field')
                load(fullfile(path,file),'Field');
                assignin('base', 'Field', Field);
                app.xEditField.Value = Field.B0dir(1);
                app.yEditField.Value = Field.B0dir(2);
                app.zEditField.Value = Field.B0dir(3);
                app.BackgroundFieldDropDown.Value = Field.Background;
                app.EditField.Value = 'Field loaded successfully';
                drawnow();
            end
        end

        % Value changed function: BackgroundFieldDropDown
        function BackgroundFieldDropDownValueChanged(app, event)
            value = app.BackgroundFieldDropDown.Value;
            if value == "Removed"
                app.ShimmingCheckBox.Value = false;
                app.ShimmingCheckBox.Enable = false;
            else
                app.ShimmingCheckBox.Enable = true;
            end
        end

        % Button pushed function: SimulateFieldButton
        function SimulateFieldButtonPushed(app, event)
            if evalin('base', 'exist("Phantom","var")==1')
                app.EditField.Value = 'Evaluating demagnetizing field...';
                drawnow();
                Phantom = evalin('base','Phantom');
                B0dir = [app.xEditField.Value, app.yEditField.Value, app.zEditField.Value];
                eval_field(Phantom,B0dir,app.BackgroundFieldDropDown.Value);
                app.EditField.Value = 'Field calculated successfully';
                drawnow();
            else
                app.EditField.Value = 'Phantom struct not found';
                drawnow();
            end
        end

        % Button pushed function: ViewFieldButton
        function ViewFieldButtonPushed(app, event)
            if evalin('base', 'exist("Field","var")==1')
                Field = evalin('base','Field');
                imshow3Dfull(Field.FieldImg);
            else
                app.EditField.Value = 'Field struct not found';
                drawnow();
            end
        end

        % Button pushed function: MRISimulationButton
        function MRISimulationButtonPushed(app, event)
            if evalin('base', 'exist("Field","var")==1') && evalin('base', 'exist("Phantom","var")==1')
                %creating class of sequence parameters
                SeqParams.TR = app.TRsEditField.Value;
                SeqParams.FlipAngle = app.FlipangledegEditField.Value;
                te1 = app.FirstTEsEditField.Value;
                dte = app.DeltaTEsEditField.Value;
                N = app.NoechoesEditField.Value;
                te = te1 + (0:N-1)*dte;
                SeqParams.TE = te;
                
                if SeqParams.TE(N)>SeqParams.TR
                    app.EditField.Value = 'Last TE is longer than TR';
                    drawnow();
                else
                    app.EditField.Value = 'Simulating MR measurement...';
                    drawnow();
                    %creating class of simulation parameters
                    SimParams.B0 = app.B0TEditField.Value;
                    SimParams.Noise = app.NoiseCheckBox.Value;
                    if SimParams.Noise == true
                        SimParams.SNR = app.SNRdBEditField.Value;
                    else
                        SimParams.SNR = NaN;
                    end
                
                    Phantom = evalin('base','Phantom');
                    Field = evalin('base','Field');
                    mri_simulation(Phantom,Field,SeqParams,SimParams);
                    app.EditField.Value = 'MR simulation ended successfully';
                    drawnow();
                    figure(app.ForwardQSMSimulatorUIFigure)
                end
            else
                app.EditField.Value = 'Phantom or Field struct not found';
                drawnow();
            end
        end

        % Value changed function: NoiseCheckBox
        function NoiseCheckBoxValueChanged(app, event)
            value = app.NoiseCheckBox.Value;
            if value == true
                    app.SNRdBEditField.Enable=true;
                else
                    app.SNRdBEditField.Enable=false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForwardQSMSimulatorUIFigure = uifigure('Visible', 'off');
            app.ForwardQSMSimulatorUIFigure.Name = 'Forward QSM Simulator';
            app.GridLayout = uigridlayout(app.ForwardQSMSimulatorUIFigure);
            app.SpecifyphantomparametersLabel = uilabel(app.GridLayout);
            app.SpecifyphantomparametersLabel.Text = 'Specify phantom parameters';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Label'; 'M0 [a.u.]'; 'R1 [1/s]'; 'R2* [1/s]'; 'Susceptibility [ppm]'; 'a'; 'b'; 'c'; 'x0'; 'y0'; 'z0'; 'phi [deg]'; 'theta [deg]'; 'psi [deg]'};
            app.UITable.RowName = {''; ''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PhantomCreationPanel = uipanel(app.GridLayout);
            app.PhantomCreationPanel.Title = 'Phantom Creation';
            app.GridLayout2 = uigridlayout(app.PhantomCreationPanel);
            app.nSpinnerLabel = uilabel(app.GridLayout2);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.GridLayout2);
            app.nSpinner.Limits = [2 Inf];
            app.nSpinner.Value = 2;
            app.AddrowButton = uibutton(app.GridLayout2, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = 'Add row';
            app.InsertrowButton = uibutton(app.GridLayout2, 'push');
            app.InsertrowButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrowButtonPushed, true);
            app.InsertrowButton.Text = 'Insert row';
            app.RemoverowButton = uibutton(app.GridLayout2, 'push');
            app.RemoverowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverowButtonPushed, true);
            app.RemoverowButton.Text = 'Remove row';
            app.CleartableButton = uibutton(app.GridLayout2, 'push');
            app.CleartableButton.ButtonPushedFcn = createCallbackFcn(app, @CleartableButtonPushed, true);
            app.CleartableButton.Text = 'Clear table';
            app.BackgroundDropDown = uidropdown(app.GridLayout2);
            app.BackgroundDropDown.Items = {'Air', 'Vacuum', ''};
            app.BackgroundDropDown.Value = 'Air';
            app.BackgroundtypeLabel = uilabel(app.GridLayout2);
            app.BackgroundtypeLabel.Text = {'Background type'; ''};
            app.CreatePhantomButton = uibutton(app.GridLayout2, 'push');
            app.CreatePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePhantomButtonPushed, true);
            app.CreatePhantomButton.Text = 'Create Phantom';
            app.ViewPhantomButton = uibutton(app.GridLayout2, 'push');
            app.ViewPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPhantomButtonPushed, true);
            app.ViewPhantomButton.Text = 'View Phantom';
            app.LoadPhantomButton = uibutton(app.GridLayout2, 'push');
            app.LoadPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPhantomButtonPushed, true);
            app.LoadPhantomButton.Text = 'Load Phantom';
            app.SavePhantomButton = uibutton(app.GridLayout2, 'push');
            app.SavePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhantomButtonPushed, true);
            app.SavePhantomButton.Text = 'Save Phantom';
            app.VoxelsizemmLabel = uilabel(app.GridLayout2);
            app.VoxelsizemmLabel.Text = 'Voxel size [mm]';
            app.xEditField_2Label_2 = uilabel(app.GridLayout2);
            app.xEditField_2Label_2.Text = 'x';
            app.xEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.xEditField_2.Limits = [0 Inf];
            app.xEditField_2.Value = 1;
            app.yEditField_2Label_2 = uilabel(app.GridLayout2);
            app.yEditField_2Label_2.Text = 'y';
            app.yEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.yEditField_2.Limits = [0 Inf];
            app.yEditField_2.Value = 1;
            app.zEditField_2Label_2 = uilabel(app.GridLayout2);
            app.zEditField_2Label_2.Text = 'z';
            app.zEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.zEditField_2.Limits = [0 Inf];
            app.zEditField_2.Value = 1;
            app.MatrixsizeLabel = uilabel(app.GridLayout2);
            app.MatrixsizeLabel.Text = 'Matrix size';
            app.xEditField_3Label = uilabel(app.GridLayout2);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.xEditField_3.Limits = [1 Inf];
            app.xEditField_3.RoundFractionalValues = 'on';
            app.xEditField_3.Value = 256;
            app.yEditField_3Label = uilabel(app.GridLayout2);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.yEditField_3.Limits = [1 Inf];
            app.yEditField_3.RoundFractionalValues = 'on';
            app.yEditField_3.Value = 256;
            app.zEditField_3Label = uilabel(app.GridLayout2);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.zEditField_3.Limits = [1 Inf];
            app.zEditField_3.RoundFractionalValues = 'on';
            app.zEditField_3.Value = 256;
            app.FieldSimulationPanel = uipanel(app.GridLayout);
            app.FieldSimulationPanel.Title = 'Field Simulation';
            app.GridLayout2_2 = uigridlayout(app.FieldSimulationPanel);
            app.SimulateFieldButton = uibutton(app.GridLayout2_2, 'push');
            app.SimulateFieldButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateFieldButtonPushed, true);
            app.SimulateFieldButton.Text = 'Simulate Field';
            app.ViewFieldButton = uibutton(app.GridLayout2_2, 'push');
            app.ViewFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFieldButtonPushed, true);
            app.ViewFieldButton.Text = 'View Field';
            app.LoadFieldButton = uibutton(app.GridLayout2_2, 'push');
            app.LoadFieldButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFieldButtonPushed, true);
            app.LoadFieldButton.Text = 'Load Field';
            app.SaveFieldButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveFieldButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFieldButtonPushed, true);
            app.SaveFieldButton.Text = 'Save Field';
            app.xEditFieldLabel = uilabel(app.GridLayout2_2);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout2_2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout2_2);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.zEditField.Value = 1;
            app.B0directionLabel = uilabel(app.GridLayout2_2);
            app.B0directionLabel.Text = 'B0 direction';
            app.BackgroundFieldDropDownLabel = uilabel(app.GridLayout2_2);
            app.BackgroundFieldDropDownLabel.Text = 'Background Field';
            app.BackgroundFieldDropDown = uidropdown(app.GridLayout2_2);
            app.BackgroundFieldDropDown.Items = {'Not removed', 'Removed', ''};
            app.BackgroundFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundFieldDropDownValueChanged, true);
            app.BackgroundFieldDropDown.Value = 'Not removed';
            app.MRISimulationPanel = uipanel(app.GridLayout);
            app.MRISimulationPanel.Title = 'MRI Simulation';
            app.GridLayout2_3 = uigridlayout(app.MRISimulationPanel);
            app.TRsEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.TRsEditField.Limits = [0 Inf];
            app.TRsEditField.Value = 0.04;
            app.TRsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.TRsEditFieldLabel.Text = 'TR [s]';
            app.MRISimulationButton = uibutton(app.GridLayout2_3, 'push');
            app.MRISimulationButton.ButtonPushedFcn = createCallbackFcn(app, @MRISimulationButtonPushed, true);
            app.MRISimulationButton.Text = 'MRI Simulation';
            app.FirstTEsEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.FirstTEsEditField.Limits = [0 Inf];
            app.FirstTEsEditField.Value = 0.004;
            app.FirstTEsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.FirstTEsEditFieldLabel.Text = 'First TE [s]';
            app.DeltaTEsEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.DeltaTEsEditField.Limits = [0 Inf];
            app.DeltaTEsEditField.Value = 0.008;
            app.DeltaTEsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.DeltaTEsEditFieldLabel.Text = 'Delta TE [s]';
            app.NoechoesEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.NoechoesEditField.Limits = [1 Inf];
            app.NoechoesEditField.RoundFractionalValues = 'on';
            app.NoechoesEditField.Value = 5;
            app.NoechoesEditFieldLabel = uilabel(app.GridLayout2_3);
            app.NoechoesEditFieldLabel.Text = 'No. echoes';
            app.FlipangledegEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.FlipangledegEditField.Limits = [0 Inf];
            app.FlipangledegEditField.Value = 23;
            app.FlipangledegEditFieldLabel = uilabel(app.GridLayout2_3);
            app.FlipangledegEditFieldLabel.Text = 'Flip angle [deg]';
            app.NoiseCheckBox = uicheckbox(app.GridLayout2_3);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseCheckBoxValueChanged, true);
            app.NoiseCheckBox.Text = 'Noise';
            app.SNRdBLabel = uilabel(app.GridLayout2_3);
            app.SNRdBLabel.Text = 'SNR [dB]';
            app.SNRdBEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.SNRdBEditField.Enable = 'off';
            app.SNRdBEditField.Value = 50;
            app.B0TEditFieldLabel = uilabel(app.GridLayout2_3);
            app.B0TEditFieldLabel.Text = 'B0 [T]';
            app.B0TEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.B0TEditField.Limits = [0 Inf];
            app.B0TEditField.Value = 7;
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.ForwardQSMSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForwardQSM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForwardQSMSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForwardQSMSimulatorUIFigure)
        end
    end
end